classdef RobotControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        CenterPanel                 matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        ReengageSwitch              matlab.ui.control.ToggleSwitch
        EmergencyStopButton         matlab.ui.control.StateButton
        LoadRobotButton             matlab.ui.control.Button
        ZMinusButton                matlab.ui.control.Button
        ZPlusButton                 matlab.ui.control.Button
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        YMinusButton                matlab.ui.control.Button
        YPlusButton                 matlab.ui.control.Button
        YEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        XMinusButton                matlab.ui.control.Button
        XPlusButton                 matlab.ui.control.Button
        XEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        Link6EditField              matlab.ui.control.NumericEditField
        Link5EditField              matlab.ui.control.NumericEditField
        Link4EditField              matlab.ui.control.NumericEditField
        Link3EditField              matlab.ui.control.NumericEditField
        Link2EditField              matlab.ui.control.NumericEditField
        Link1EditField              matlab.ui.control.NumericEditField
        EndEffectorSlider           matlab.ui.control.Slider
        EndEffectorSliderLabel      matlab.ui.control.Label
        Link5Slider                 matlab.ui.control.Slider
        Link5SliderLabel            matlab.ui.control.Label
        ForearmSlider               matlab.ui.control.Slider
        ForearmSliderLabel          matlab.ui.control.Label
        RearArmSlider               matlab.ui.control.Slider
        RearArmSliderLabel          matlab.ui.control.Label
        BaseSlider                  matlab.ui.control.Slider
        BaseSliderLabel             matlab.ui.control.Label
        LinearRailSlider            matlab.ui.control.Slider
        LinearRailSliderLabel       matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        ofSugarCubesEditField       matlab.ui.control.NumericEditField
        ofSugarCubesEditFieldLabel  matlab.ui.control.Label
        CustomiseYourOrderLabel     matlab.ui.control.Label
        SelectYourMilkButtonGroup   matlab.ui.container.ButtonGroup
        NoneButton                  matlab.ui.control.RadioButton
        SkimButton                  matlab.ui.control.RadioButton
        FullCreamButton             matlab.ui.control.RadioButton
        PlaceOrderButton            matlab.ui.control.Button
        SelectYourTeaButtonGroup    matlab.ui.container.ButtonGroup
        ChamomileButton             matlab.ui.control.RadioButton
        GreenTeaButton              matlab.ui.control.RadioButton
        EnglishBreakfastButton      matlab.ui.control.RadioButton
        Lamp                        matlab.ui.control.Lamp
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        obj % The starter class
        modelDobot % The loaded Dobot
        qz = [0     0     deg2rad(5)     deg2rad(20)     0     0];
        qr = [0	    pi/2  -pi/2	        0	             0	   0];
    end
    
    methods (Access = private)
        
        function EnableSliders(app, bool)
            app.LinearRailSlider.Enable = bool;
            app.BaseSlider.Enable = bool;
            app.RearArmSlider.Enable = bool;
            app.ForearmSlider.Enable = bool;
            app.Link5Slider.Enable = bool;
            app.EndEffectorSlider.Enable = bool;

            app.Link1EditField.Enable = bool;
            app.Link2EditField.Enable = bool;
            app.Link3EditField.Enable = bool;
            app.Link4EditField.Enable = bool;
            app.Link5EditField.Enable = bool;
            app.Link6EditField.Enable = bool;
        end
        
        function EnableCartesianButtons(app, bool)
            app.XPlusButton.Enable = bool;
            app.XMinusButton.Enable = bool;
            app.YPlusButton.Enable = bool;
            app.YMinusButton.Enable = bool;
            app.ZPlusButton.Enable = bool;
            app.ZMinusButton.Enable = bool;

            app.XEditField.Enable = bool;
            app.YEditField.Enable = bool;
            app.ZEditField.Enable = bool;
        end
        
        function position = MoveCartesian(app, dimension, value, fromButton)
            switch dimension
                case 'x'
                    a = 1;
                case 'y'
                    a = 2;
                case 'z'
                    a = 3;
                otherwise
                    disp("Invalid dimension requested - x used as default");
                    a = 1;
            end
                q = app.modelDobot.model.getpos;
                tr = app.modelDobot.model.fkine(q);
            if fromButton
                tr(a,4) = tr(a,4) + value;           
            else 
                tr(a,4) = value;
            end 
            position = tr(a,4);
            newQ = app.modelDobot.model.ikcon(tr,q);
            app.modelDobot.model.animate(newQ);
            SetLinkValues(app, newQ);
        end
        
        function MoveLink(app, linkNumber, value)
            q = app.modelDobot.model.getpos;
            newQ = q;
            if linkNumber == 1
               newQ(1,linkNumber) = value;
            else
                newQ(1,linkNumber) = deg2rad(value);
            end
            app.modelDobot.model.animate(newQ);
            
%             steps = 100;                                      %% Ask which method to use
%             modelTraj = jtraj(q,newQ,steps);
%             for i = 1:steps
%                 model.animate(modelTraj(i,:));
%                 drawnow()
%             end

            tr = app.modelDobot.model.fkine(newQ);   
            SetCartesianValues(app, tr)
        end
        
        function InitialiseFields(app)
            q = app.modelDobot.model.getpos;
            SetLinkValues(app, q);              %% Buggy
            tr = app.modelDobot.model.fkine(q);      
            SetCartesianValues(app, tr);
        end
        
        function SetLinkValues(app, q)
            app.Link1EditField.Value = rad2deg(q(1,1));
            app.Link2EditField.Value = rad2deg(q(1,2));
            app.Link3EditField.Value = rad2deg(q(1,3));
            app.Link4EditField.Value = rad2deg(q(1,4));
            app.Link5EditField.Value = rad2deg(q(1,5));
            app.Link6EditField.Value = rad2deg(q(1,6));

            app.LinearRailSlider.Value = rad2deg(q(1,1));
            app.BaseSlider.Value = rad2deg(q(1,2));
            app.RearArmSlider.Value = rad2deg(q(1,3));
            app.ForearmSlider.Value = rad2deg(q(1,4));
            app.Link5Slider.Value = rad2deg(q(1,5));
            app.EndEffectorSlider.Value = rad2deg(q(1,6));
        end
        
        function SetCartesianValues(app, tr)
            app.XEditField.Value = tr(1,4);
            app.YEditField.Value = tr(2,4);
            app.ZEditField.Value = tr(3,4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadRobotButton
        function LoadRobotButtonPushed(app, event)
            %axes(app.UIAxes);
            %cla(app.UIAxes)
            
            % Make, save and plot some robots            
%             figure('visible','off'); % Makes the figure invisible when loading
%             ax = gca;
%             app.modelDobot = LinearDobot(false);
%             %app.modelDobot.base = transl([0.5,0,0]);
%             %plot(app.modelDobot,app.qr)
%             app.modelDobot.model.animate(app.qz);
%             drawnow();
%             hold on;            
%             
%             % Delete the green floor surface (need to count backwards
%             % because once it is deleted the array is compressed)
%             for i = size(ax.Children,1):-1:1
%                 if strcmp(ax.Children(i).Type,'surface')
%                     delete(ax.Children(i));
%                 end
%             end      
            
            app.obj = Assignment2Starter(false);            
            app.modelDobot = app.obj.robot; %% remove and replace with app.obj.robot?

            % Turn on lamp
            app.Lamp.Color = 'green';

            % Turn off load button and enable the move buttons and sliders
            app.EmergencyStopButton.Enable = true;
            app.LoadRobotButton.Enable = false;
            EnableCartesianButtons(app,true);
            EnableSliders(app,true);

            % Initialise values
            InitialiseFields(app);

        end

        % Value changed function: EmergencyStopButton
        function EmergencyStopButtonValueChanged(app, event)
            value = app.EmergencyStopButton.Value;
            if value
                app.ReengageSwitch.Value = 'Off';
                app.Lamp.Color = 'red';
                app.PlaceOrderButton.Enable = false;
                EnableCartesianButtons(app,false);
                EnableSliders(app,false);
                app.obj.h = value;
            else 
                app.ReengageSwitch.Enable = true;
            end
        end

        % Value changed function: ReengageSwitch
        function ReengageSwitchValueChanged(app, event)
            %value = app.ReengageSwitch.Value;
            app.Lamp.Color = 'green';
            app.PlaceOrderButton.Enable = true;
            EnableCartesianButtons(app,true);
            EnableSliders(app,true);
            app.ReengageSwitch.Enable = false;
            app.obj.h = false;
        end

        % Button pushed function: XPlusButton
        function XPlusButtonPushed(app, event)
            x = MoveCartesian(app,'x',0.01,true);
            app.XEditField.Value = x;
        end

        % Button pushed function: XMinusButton
        function XMinusButtonPushed(app, event)
            x = MoveCartesian(app,'x',-0.01,true);
            app.XEditField.Value = x;
        end

        % Button pushed function: YPlusButton
        function YPlusButtonPushed(app, event)
            y = MoveCartesian(app,'y',0.01,true);
            app.YEditField.Value = y;
        end

        % Button pushed function: YMinusButton
        function YMinusButtonPushed(app, event)
            y = MoveCartesian(app,'y',-0.01,true);
            app.YEditField.Value = y;
        end

        % Button pushed function: ZPlusButton
        function ZPlusButtonPushed(app, event)
            z = MoveCartesian(app,'z',0.01,true);
            app.ZEditField.Value = z;
        end

        % Button pushed function: ZMinusButton
        function ZMinusButtonPushed(app, event)
            z = MoveCartesian(app,'z',-0.01,true);
            app.ZEditField.Value = z;
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            MoveCartesian(app,'x',value,false);
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            MoveCartesian(app,'y',value,false);          
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            MoveCartesian(app,'z',value,false);
        end

        % Value changed function: Link1EditField
        function Link1EditFieldValueChanged(app, event)
            value = app.Link1EditField.Value;
            app.LinearRailSlider.Value = value;
            MoveLink(app, 1, value);
        end

        % Value changed function: Link2EditField
        function Link2EditFieldValueChanged(app, event)
            value = app.Link2EditField.Value;
            app.BaseSlider.Value = value;
            MoveLink(app, 2, value);
        end

        % Value changed function: Link3EditField
        function Link3EditFieldValueChanged(app, event)
            value = app.Link3EditField.Value;
            app.RearArmSlider.Value = value;
            MoveLink(app, 3, value);            
        end

        % Value changed function: Link4EditField
        function Link4EditFieldValueChanged(app, event)
            value = app.Link4EditField.Value;
            app.ForearmSlider.Value = value;
            MoveLink(app, 4, value);
        end

        % Value changed function: Link5EditField
        function Link5EditFieldValueChanged(app, event)
            value = app.Link5EditField.Value;
            app.Link5Slider.Value = value;
            MoveLink(app, 5, value);
        end

        % Value changed function: Link6EditField
        function Link6EditFieldValueChanged(app, event)
            value = app.Link6EditField.Value;
            app.EndEffectorSlider.Value = value;
            MoveLink(app, 2, value);
        end

        % Value changed function: LinearRailSlider
        function LinearRailSliderValueChanged(app, event)
            value = app.LinearRailSlider.Value;
            app.Link1EditField.Value = value;
            MoveLink(app, 1, value);
        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            value = app.BaseSlider.Value;
            app.Link2EditField.Value = value;
            MoveLink(app, 2, value);
        end

        % Value changed function: RearArmSlider
        function RearArmSliderValueChanged(app, event)
            value = app.RearArmSlider.Value;
            app.Link3EditField.Value = value;
            MoveLink(app, 3, value);
        end

        % Value changed function: ForearmSlider
        function ForearmSliderValueChanged(app, event)
            value = app.ForearmSlider.Value;
            app.Link4EditField.Value = value;
            MoveLink(app, 4, value);
        end

        % Value changed function: Link5Slider
        function Link5SliderValueChanged(app, event)
            value = app.Link5Slider.Value;
            app.Link5EditField.Value = value;
            MoveLink(app, 5, value);
        end

        % Value changed function: EndEffectorSlider
        function EndEffectorSliderValueChanged(app, event)
            value = app.EndEffectorSlider.Value;
            app.Link6EditField.Value = value;
            MoveLink(app, 6, value);
        end

        % Value changing function: LinearRailSlider
        function LinearRailSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link1EditField.Value = changingValue;
            MoveLink(app, 1, changingValue);
        end

        % Value changing function: BaseSlider
        function BaseSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link2EditField.Value = changingValue;
            MoveLink(app, 2, changingValue);
        end

        % Value changing function: RearArmSlider
        function RearArmSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link3EditField.Value = changingValue;
            MoveLink(app, 3, changingValue);
        end

        % Value changing function: ForearmSlider
        function ForearmSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link4EditField.Value = changingValue;
            MoveLink(app, 4, changingValue);
        end

        % Value changing function: Link5Slider
        function Link5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link5EditField.Value = changingValue;
            MoveLink(app, 5, changingValue);
        end

        % Value changing function: EndEffectorSlider
        function EndEffectorSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link6EditField.Value = changingValue;
            MoveLink(app, 6, changingValue);
        end

        % Button pushed function: PlaceOrderButton
        function PlaceOrderButtonPushed(app, event)
            switch app.SelectYourTeaButtonGroup.SelectedObject
                case app.EnglishBreakfastButton
                    selectedTea = 1;
                case app.GreenTeaButton
                    selectedTea = 2;
                case app.ChamomileButton
                    selectedTea = 3;
            end
            switch app.SelectYourMilkButtonGroup.SelectedObject
                case app.NoneButton
                    selectedMilk = 0;
                case app.FullCreamButton
                    selectedMilk = 1;
                case app.SkimButton
                    selectedMilk = 2;
            end 
            sugarQuantity = app.ofSugarCubesEditField.Value;
            app.obj.MakeTea(selectedTea,selectedMilk,sugarQuantity);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x', 228};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.LinearRailSliderLabel = uilabel(app.GridLayout2);
            app.LinearRailSliderLabel.Text = 'Linear Rail';
            app.LinearRailSlider = uislider(app.GridLayout2);
            app.LinearRailSlider.Limits = [-1.33 0];
            app.LinearRailSlider.ValueChangedFcn = createCallbackFcn(app, @LinearRailSliderValueChanged, true);
            app.LinearRailSlider.ValueChangingFcn = createCallbackFcn(app, @LinearRailSliderValueChanging, true);
            app.LinearRailSlider.Enable = 'off';
            app.BaseSliderLabel = uilabel(app.GridLayout2);
            app.BaseSliderLabel.Text = 'Base';
            app.BaseSlider = uislider(app.GridLayout2);
            app.BaseSlider.Limits = [-135 135];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.BaseSlider.ValueChangingFcn = createCallbackFcn(app, @BaseSliderValueChanging, true);
            app.BaseSlider.Enable = 'off';
            app.RearArmSliderLabel = uilabel(app.GridLayout2);
            app.RearArmSliderLabel.Text = 'Rear Arm';
            app.RearArmSlider = uislider(app.GridLayout2);
            app.RearArmSlider.Limits = [5 80];
            app.RearArmSlider.ValueChangedFcn = createCallbackFcn(app, @RearArmSliderValueChanged, true);
            app.RearArmSlider.ValueChangingFcn = createCallbackFcn(app, @RearArmSliderValueChanging, true);
            app.RearArmSlider.Enable = 'off';
            app.RearArmSlider.Value = 5;
            app.ForearmSliderLabel = uilabel(app.GridLayout2);
            app.ForearmSliderLabel.Text = 'Forearm';
            app.ForearmSlider = uislider(app.GridLayout2);
            app.ForearmSlider.Limits = [-5 85];
            app.ForearmSlider.ValueChangedFcn = createCallbackFcn(app, @ForearmSliderValueChanged, true);
            app.ForearmSlider.ValueChangingFcn = createCallbackFcn(app, @ForearmSliderValueChanging, true);
            app.ForearmSlider.Enable = 'off';
            app.ForearmSlider.Value = 15;
            app.Link5SliderLabel = uilabel(app.GridLayout2);
            app.Link5SliderLabel.Visible = 'off';
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5Slider = uislider(app.GridLayout2);
            app.Link5Slider.Limits = [-180 180];
            app.Link5Slider.ValueChangedFcn = createCallbackFcn(app, @Link5SliderValueChanged, true);
            app.Link5Slider.ValueChangingFcn = createCallbackFcn(app, @Link5SliderValueChanging, true);
            app.Link5Slider.Enable = 'off';
            app.Link5Slider.Visible = 'off';
            app.EndEffectorSliderLabel = uilabel(app.GridLayout2);
            app.EndEffectorSliderLabel.Text = 'End Effector';
            app.EndEffectorSlider = uislider(app.GridLayout2);
            app.EndEffectorSlider.Limits = [-85 85];
            app.EndEffectorSlider.ValueChangedFcn = createCallbackFcn(app, @EndEffectorSliderValueChanged, true);
            app.EndEffectorSlider.ValueChangingFcn = createCallbackFcn(app, @EndEffectorSliderValueChanging, true);
            app.EndEffectorSlider.Enable = 'off';
            app.Link1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link1EditField.Limits = [-1.33 0];
            app.Link1EditField.ValueDisplayFormat = '%.2f';
            app.Link1EditField.ValueChangedFcn = createCallbackFcn(app, @Link1EditFieldValueChanged, true);
            app.Link1EditField.Enable = 'off';
            app.Link2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link2EditField.Limits = [-135 135];
            app.Link2EditField.ValueDisplayFormat = '%.2f';
            app.Link2EditField.ValueChangedFcn = createCallbackFcn(app, @Link2EditFieldValueChanged, true);
            app.Link2EditField.Enable = 'off';
            app.Link3EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link3EditField.Limits = [5 80];
            app.Link3EditField.ValueDisplayFormat = '%.2f';
            app.Link3EditField.ValueChangedFcn = createCallbackFcn(app, @Link3EditFieldValueChanged, true);
            app.Link3EditField.Enable = 'off';
            app.Link3EditField.Value = 5;
            app.Link4EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link4EditField.Limits = [-5 85];
            app.Link4EditField.ValueDisplayFormat = '%.2f';
            app.Link4EditField.ValueChangedFcn = createCallbackFcn(app, @Link4EditFieldValueChanged, true);
            app.Link4EditField.Enable = 'off';
            app.Link4EditField.Value = 15;
            app.Link5EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link5EditField.Limits = [-180 180];
            app.Link5EditField.ValueDisplayFormat = '%.2f';
            app.Link5EditField.ValueChangedFcn = createCallbackFcn(app, @Link5EditFieldValueChanged, true);
            app.Link5EditField.Enable = 'off';
            app.Link5EditField.Visible = 'off';
            app.Link6EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link6EditField.Limits = [-85 85];
            app.Link6EditField.ValueDisplayFormat = '%.2f';
            app.Link6EditField.ValueChangedFcn = createCallbackFcn(app, @Link6EditFieldValueChanged, true);
            app.Link6EditField.Enable = 'off';
            app.XEditField = uieditfield(app.GridLayout2, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.2f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Enable = 'off';
            app.XEditFieldLabel = uilabel(app.GridLayout2);
            app.XEditFieldLabel.Text = 'X';
            app.XPlusButton = uibutton(app.GridLayout2, 'push');
            app.XPlusButton.ButtonPushedFcn = createCallbackFcn(app, @XPlusButtonPushed, true);
            app.XPlusButton.Icon = 'up_button.png';
            app.XPlusButton.Enable = 'off';
            app.XPlusButton.Text = '';
            app.XMinusButton = uibutton(app.GridLayout2, 'push');
            app.XMinusButton.ButtonPushedFcn = createCallbackFcn(app, @XMinusButtonPushed, true);
            app.XMinusButton.Icon = 'down_button.png';
            app.XMinusButton.Enable = 'off';
            app.XMinusButton.Text = '';
            app.YEditField = uieditfield(app.GridLayout2, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.2f';
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Enable = 'off';
            app.YEditFieldLabel = uilabel(app.GridLayout2);
            app.YEditFieldLabel.Text = 'Y';
            app.YPlusButton = uibutton(app.GridLayout2, 'push');
            app.YPlusButton.ButtonPushedFcn = createCallbackFcn(app, @YPlusButtonPushed, true);
            app.YPlusButton.Icon = 'up_button.png';
            app.YPlusButton.Enable = 'off';
            app.YPlusButton.Text = '';
            app.YMinusButton = uibutton(app.GridLayout2, 'push');
            app.YMinusButton.ButtonPushedFcn = createCallbackFcn(app, @YMinusButtonPushed, true);
            app.YMinusButton.Icon = 'down_button.png';
            app.YMinusButton.Enable = 'off';
            app.YMinusButton.Text = '';
            app.ZEditFieldLabel = uilabel(app.GridLayout2);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.2f';
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Enable = 'off';
            app.ZPlusButton = uibutton(app.GridLayout2, 'push');
            app.ZPlusButton.ButtonPushedFcn = createCallbackFcn(app, @ZPlusButtonPushed, true);
            app.ZPlusButton.Icon = 'up_button.png';
            app.ZPlusButton.Enable = 'off';
            app.ZPlusButton.Text = '';
            app.ZMinusButton = uibutton(app.GridLayout2, 'push');
            app.ZMinusButton.ButtonPushedFcn = createCallbackFcn(app, @ZMinusButtonPushed, true);
            app.ZMinusButton.Icon = 'down_button.png';
            app.ZMinusButton.Enable = 'off';
            app.ZMinusButton.Text = '';
            app.LoadRobotButton = uibutton(app.GridLayout2, 'push');
            app.LoadRobotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRobotButtonPushed, true);
            app.LoadRobotButton.Text = 'Load Robot';
            app.EmergencyStopButton = uibutton(app.GridLayout2, 'state');
            app.EmergencyStopButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyStopButtonValueChanged, true);
            app.EmergencyStopButton.Enable = 'off';
            app.EmergencyStopButton.Icon = 'stop_button.jpg';
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.ReengageSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.ReengageSwitch.ValueChangedFcn = createCallbackFcn(app, @ReengageSwitchValueChanged, true);
            app.ReengageSwitch.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.Lamp = uilamp(app.GridLayout4);
            app.SelectYourTeaButtonGroup = uibuttongroup(app.GridLayout4);
            app.SelectYourTeaButtonGroup.Title = 'Select Your Tea';
            app.EnglishBreakfastButton = uiradiobutton(app.SelectYourTeaButtonGroup);
            app.EnglishBreakfastButton.Text = 'English Breakfast';
            app.EnglishBreakfastButton.Value = true;
            app.GreenTeaButton = uiradiobutton(app.SelectYourTeaButtonGroup);
            app.GreenTeaButton.Text = 'Green Tea';
            app.ChamomileButton = uiradiobutton(app.SelectYourTeaButtonGroup);
            app.ChamomileButton.Text = 'Chamomile';
            app.PlaceOrderButton = uibutton(app.GridLayout4, 'push');
            app.PlaceOrderButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceOrderButtonPushed, true);
            app.PlaceOrderButton.Text = 'Place Order';
            app.SelectYourMilkButtonGroup = uibuttongroup(app.GridLayout4);
            app.SelectYourMilkButtonGroup.Title = 'Select Your Milk';
            app.FullCreamButton = uiradiobutton(app.SelectYourMilkButtonGroup);
            app.FullCreamButton.Text = 'Full Cream';
            app.SkimButton = uiradiobutton(app.SelectYourMilkButtonGroup);
            app.SkimButton.Text = 'Skim';
            app.NoneButton = uiradiobutton(app.SelectYourMilkButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.CustomiseYourOrderLabel = uilabel(app.GridLayout4);
            app.CustomiseYourOrderLabel.Text = 'Customise Your Order';
            app.ofSugarCubesEditFieldLabel = uilabel(app.GridLayout4);
            app.ofSugarCubesEditFieldLabel.Text = '# of Sugar Cubes';
            app.ofSugarCubesEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ofSugarCubesEditField.Limits = [0 10];
            app.ofSugarCubesEditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end